classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DigitalImageProcessingByKodoYousifUIFigure  matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        ResultsPanel     matlab.ui.container.Panel
        GridLayout8      matlab.ui.container.GridLayout
        GridLayout18     matlab.ui.container.GridLayout
        GridLayout25     matlab.ui.container.GridLayout
        TargetButton_8   matlab.ui.control.Button
        SelectButton_8   matlab.ui.control.Button
        UIAxes12         matlab.ui.control.UIAxes
        GridLayout17     matlab.ui.container.GridLayout
        GridLayout26     matlab.ui.container.GridLayout
        TargetButton_9   matlab.ui.control.Button
        SelectButton_9   matlab.ui.control.Button
        UIAxes13         matlab.ui.control.UIAxes
        GridLayout16     matlab.ui.container.GridLayout
        GridLayout23     matlab.ui.container.GridLayout
        TargetButton_6   matlab.ui.control.Button
        SelectButton_6   matlab.ui.control.Button
        UIAxes10         matlab.ui.control.UIAxes
        GridLayout15     matlab.ui.container.GridLayout
        GridLayout24     matlab.ui.container.GridLayout
        TargetButton_7   matlab.ui.control.Button
        SelectButton_7   matlab.ui.control.Button
        UIAxes11         matlab.ui.control.UIAxes
        GridLayout14     matlab.ui.container.GridLayout
        GridLayout21     matlab.ui.container.GridLayout
        TargetButton_4   matlab.ui.control.Button
        SelectButton_4   matlab.ui.control.Button
        UIAxes6          matlab.ui.control.UIAxes
        GridLayout13     matlab.ui.container.GridLayout
        GridLayout22     matlab.ui.container.GridLayout
        TargetButton_5   matlab.ui.control.Button
        SelectButton_5   matlab.ui.control.Button
        UIAxes7          matlab.ui.control.UIAxes
        GridLayout12     matlab.ui.container.GridLayout
        GridLayout20     matlab.ui.container.GridLayout
        TargetButton_3   matlab.ui.control.Button
        SelectButton_3   matlab.ui.control.Button
        UIAxes9          matlab.ui.control.UIAxes
        GridLayout11     matlab.ui.container.GridLayout
        GridLayout19     matlab.ui.container.GridLayout
        TargetButton_2   matlab.ui.control.Button
        SelectButton_2   matlab.ui.control.Button
        UIAxes8          matlab.ui.control.UIAxes
        GridLayout9      matlab.ui.container.GridLayout
        GridLayout10     matlab.ui.container.GridLayout
        TargetButton     matlab.ui.control.Button
        SelectButton_10  matlab.ui.control.Button
        UIAxes5          matlab.ui.control.UIAxes
        GridLayout3      matlab.ui.container.GridLayout
        GridLayout5      matlab.ui.container.GridLayout
        GridLayout7      matlab.ui.container.GridLayout
        ToGrayButton     matlab.ui.control.Button
        UploadButton     matlab.ui.control.Button
        OperationsPanel  matlab.ui.container.Panel
        GridLayout6      matlab.ui.container.GridLayout
        ListBox          matlab.ui.control.ListBox
        UIAxes4          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Target % Description
        Operation = "To Gray"; % Description
        Selected = "10"; % Description
        Res1;
        Res2;
        Res3;
        Res4;
        Res5;
        Res6;
        Res7;
        Res8;
        Res9;
        Res10;
        Res11;
        Res12;
        Res13;
    end
    
    methods (Access = private)
        
        function enableSelects(app)
            app.SelectButton_10.Enable = "on";
            app.SelectButton_9.Enable = "on";
            app.SelectButton_8.Enable = "on";
            app.SelectButton_7.Enable = "on";
            app.SelectButton_6.Enable = "on";
            app.SelectButton_5.Enable = "on";
            app.SelectButton_4.Enable = "on";
            app.SelectButton_3.Enable = "on";
            app.SelectButton_2.Enable = "on";
        end
        
        function setRes(app,res)
            if app.Selected == "10"
                app.Res5 = res;
            elseif app.Selected == "9"
                app.Res13 = res;
            elseif app.Selected == "8"
                app.Res12 = res;
            elseif app.Selected == "7"
                app.Res11 = res;
            elseif app.Selected == "6"
                app.Res10 = res;
            elseif app.Selected == "5"
                app.Res7 = res;
            elseif app.Selected == "4"
                app.Res6 = res;
            elseif app.Selected == "3"
                app.Res9 = res;
            elseif app.Selected == "2"
                app.Res8 = res;
            end
        end
        
        function uiParent = getUi(app)
            if app.Selected == "10"
                uiParent = app.UIAxes5;
            elseif app.Selected == "9"
                uiParent = app.UIAxes13;
            elseif app.Selected == "8"
                uiParent = app.UIAxes12;
            elseif app.Selected == "7"
                uiParent = app.UIAxes11;
            elseif app.Selected == "6"
                uiParent = app.UIAxes10;
            elseif app.Selected == "5"
                uiParent = app.UIAxes7;
            elseif app.Selected == "4"
                uiParent = app.UIAxes6;
            elseif app.Selected == "3"
                uiParent = app.UIAxes9;
            elseif app.Selected == "2"
                uiParent = app.UIAxes8;
            end
        end
        
        function res = toGray(app)

            try
                i = app.Target;
                res = rgb2gray(i);
            catch
                res = app.Target;
            end


        end
        
        function newCtx = addNoise(app,type)

            lastImage = app.Target;
            
            try
                [M, N] = size(lastImage);
                
                noisy_img = lastImage;
                
                if type == "rayleigh"
                    b = 0.5;
                    noise = b * sqrt(-2 * log(1 - rand(M, N)));
                    noisy_img = im2double(lastImage) + noise;
                    noisy_img(noisy_img > 1) = 1;
                    noisy_img(noisy_img < 0) = 0;
                elseif type == "gamma"
                    theta = 0.5;
                    k = 2;
                    noise = gamrnd(k, theta, M, N);
                    noisy_img = im2double(lastImage) + noise;
                    noisy_img = max(min(noisy_img, 1), 0);
                elseif type == "exp"
                    lambda = 0.5;
                    noise = exprnd(1/lambda, M, N);
                    noisy_img = im2double(lastImage) + noise;
                    noisy_img = max(min(noisy_img, 1), 0);
                elseif type == "uniform"
                    minVal = -0.5;
                    maxVal = 0.5;
                    noise = minVal + (maxVal - minVal) .* rand(M, N);
                    noisy_img = im2double(lastImage) + noise;
                    noisy_img = max(min(noisy_img, 1), 0);
                else
                    noisy_img = imnoise(lastImage,type);
                end
            
                newCtx = im2uint8(noisy_img);
            catch 
                newCtx = lastImage;
            end
        
        end
    
        function res = averageFilter(app, size)

            try
                i = app.Target;
                kernel = 1/(size*size) * ones(size, size);
                res = uint8(conv2(double(i), kernel, 'same'));
            catch
                res = app.Target;
            end

        end 
        
        function res = catStretch(app)

            try
                i = app.Target;
                
                i = uint16(i);
        
                for x = 1:size(i,1)
                   
                    for y = 1:size(i,2)
                        if i(x,y) < 80
                            i(x,y) = 0 ;
                        elseif i(x,y) < 180
                            i(x,y) = 255 ;
                        else
                            i(x,y) = ( (i(x,y) - 80) * 255 ) / 100 ;
                        end
                        
                    end
                end
                
                res = uint8(i);
            catch
                res = app.Target;
            end

        end
        
        function res = centerContrast(app)

            try
                i = app.Target;
                
                desired_max_intensity = 120;
                
                scaling_factor = double(desired_max_intensity) / double(max(i(:)));
                
                stretched_image = uint8(double(i) * scaling_factor);
        
                res = stretched_image + 67;
            catch
                res = app.Target;
            end

        end
        
        function res = darkContrast(app)

            try
                i = app.Target;
                
                desired_max_intensity = 120;
                
                scaling_factor = double(desired_max_intensity) / double(max(i(:)));
                
                stretched_image = uint8(double(i) * scaling_factor);
        
                res = stretched_image;
            catch
                res = app.Target;
            end

        end
        
        function res = lightContrast(app)

            try
                i = app.Target;
                
                desired_max_intensity = 120;
                
                scaling_factor = double(desired_max_intensity) / double(max(i(:)));
                
                stretched_image = uint8(double(i) * scaling_factor);
        
                res = stretched_image + 135;
            catch
                res = app.Target;
            end

        end

        function res = fFilter(app, what, type, filterType, D0)

            try
                i = app.Target;
                
                F = fft2(double(i));
                F_centered = fftshift(F);
        
                [M, N] = size(F_centered);
        
                filter = zeros(M, N);
        
                if type == "ideal"
                    for u = 1:M
                        for v = 1:N
                            D = sqrt((u-M/2)^2 + (v-N/2)^2);
                            if D <= D0
                                filter(u, v) = 1;
                            end
                        end
                    end
                end
                
                if type == "butter"
                    n = 2;  
                    for u = 1:M
                        for v = 1:N
                            D = sqrt((u-M/2)^2 + (v-N/2)^2);
                            filter(u, v) = 1 / (1 + (D/D0)^(2*n));
                        end
                    end
                end
                
                
                if type == "gaussian"
                    for u = 1:M
                        for v = 1:N
                            D = sqrt((u-M/2)^2 + (v-N/2)^2);
                            filter(u, v) = exp(-(D^2) / (2 * (D0^2)));
                        end
                    end
                    
                end


                if filterType == "high-pass"
                   filter = 1 - filter; 
                else
                    filterType = "low-pass";
                end


                F_filtered = F_centered .* filter;
        
                F_final = ifftshift(F_filtered);
                img_filtered = ifft2(F_final);
                
        
                normalizedImage = mat2gray(real(img_filtered));
        
                uintImage = im2uint8(normalizedImage);
                
                if what == "image"
                    res = uintImage;
                elseif what == "filter"
                    res = filter;
                end
                
            catch
                res = app.Target;
            end

        end

        function res = fTransform(app,type)

            try
                i = app.Target;
                
                f = fft2(i);
        
                shift = fftshift(f);
                
                im = log(1 + abs(shift));
                
                im = im - min(im(:)); 
                im = im / max(im(:)); 
        
                im_uint8 = uint8(255 * im);
                
                if type == "f"
                    res = f;
                elseif type == "sf"
                    res = shift;
                elseif type == "m"
                    res = im_uint8;
                end
                
            catch
                res = app.Target;
            end

        end
                
        function res = gammaT(app,c,y)

            try
                i = app.Target;
                doubleValue = im2double(i);
        
                im = c * (doubleValue .^ y);
        
                im = im - min(im(:));
                im = im / max(im(:));
        
                im_uint8 = uint8(255 * im);
                
                res = im_uint8 ;
            catch
                res = app.Target;
            end

        end

        function res = gaussianF16(app)

            try
                i = app.Target;
                filter = 1/16 * [1 2 1; 2 4 2; 1 2 1];
                res = uint8(conv2(double(i), filter, 'same'));
            catch
                res = app.Target;
            end

        end
        
        function res = glr(app, bit)

            try
                i = app.Target;
                quantized_image = uint8(floor(double(i) / 2^(8-bit)));
                im = uint8(quantized_image * 2^(8-bit));
                res = im;
            catch
                res = app.Target;
            end

        end
        
        function res = gls(app)

            try
                i = app.Target;
                
                for x = 1:size(i,1)
                    for y = 1:size(i,2)
                
                        if i(x,y) < 76 & i(x,y) > 27
                            i(x,y) = 255;
                        end
                    end
                end
        
                res = i;
            catch
                res = app.Target;
            end

        end
        
        function res = hEq(app)

            try
                i = app.Target;
                res = histeq(i);
            catch
                res = app.Target;
            end

        end
        
        function res = hMa(app)

            try
                i = app.Target;
                res = imhistmatch(i,app.Res5);
            catch
                res = app.Target;
            end

        end
        
        function res = laplacian(app,what)

            try
                i = app.Target;
                
                kernal = fspecial('laplacian');
                mask = uint8(imfilter(i, kernal));
                
                if what == "mask"
                    res = mask;
                elseif what == "image"
                    res = i - mask;
                end
            
            catch
                res = app.Target;
            end

        end
        
        function res = logT(app,c)

            try
                i = app.Target;
                doubleValue = im2double(i);
                im = c * (log(doubleValue + 1));
                
                im = im - min(im(:));
                im = im / max(im(:));
        
                im_uint8 = uint8(255 * im);
                
                res = im_uint8;
            catch
                res = app.Target;
            end

        end
        
        function res = morph(app,type)

            try
                img = app.Target;
        
                se = strel('disk', 5);
                
                if type == "dilation"
                    res = imdilate(img, se);
                elseif  type == "erossion"
                    res = imerode(img, se);
                end
                
            catch
                res = app.Target;
            end

        end
        
        function res = phase(app)

            try
                i = app.Target;
                f = fft2(i);
        
                phase = angle(f);
                
                phaseOnlyImage = ifft2(exp(1i*phase));
                
                normalizedImage = mat2gray(real(phaseOnlyImage));
                
                uintImage = im2uint8(normalizedImage);
                
                res = uintImage;
            catch
                res = app.Target;
            end

        end
        
        function res = channels(app, channel, isColored)

            try
                i = app.Target;
                chan = 0 ;
    
                if channel == 'r'
                  chan = 1;  
                end
                
                if channel == 'g'
                  chan = 2;  
                end
                
                if channel == 'b'
                  chan = 3;  
                end
                    
                im = i(:,:,chan);
                
                if isColored
                   
                    if channel == "r"
                        redChannel = i(:, :, 1);
                        redImage = cat(3, redChannel, zeros(size(redChannel)), zeros(size(redChannel)));
                        im = redImage;
                    end
                    
                    if channel == "g"
                        greenChannel = i(:, :, 2);
                        greenImage = cat(3, zeros(size(greenChannel)), greenChannel, zeros(size(greenChannel)));
                        im = greenImage;
                    end
                    
                    if channel == "b"
                        blueChannel = i(:, :, 3);
                        blueImage = cat(3, zeros(size(blueChannel)), zeros(size(blueChannel)), blueChannel);
                        im = blueImage;
                    end
                end
                
                res = im;
            catch
                res = app.Target;
            end

        end
        
        function res = sFilters(app, type, size)

            try
                i = app.Target;
                kernal = ones(size,size);
        
                filtered = i;
                
                if type == "min"
                    filtered = ordfilt2(i, 1, kernal);
                end
                
                if type == "max"
                    filtered = ordfilt2(i, 9, kernal);
                end
                
                if type == "median"
                    filtered = ordfilt2(i, 5, kernal);
                end
                
                res = filtered;
            catch
                res = app.Target;
            end

        end
        
        function res = toPeaceWise80(app)

            try
                i = app.Target;
                
                for x = 1:size(i,1)
                    for y = 1:size(i,2)
                        if i(x,y) < 80
                            i(x,y) = 255;
                        end
                    end
                end
                
                res = i;
            catch
                res = app.Target;
            end

        end
        
        function res = toPeaceWiseRule(app)

            try
                i = app.Target;
                
                for x = 1:size(i,1)
                    for y = 1:size(i,2)
                        if i(x,y) < 101
                            i(x,y) = 2 * i(x,y);
                        elseif i(x,y) > 200
                             i(x,y) = 255;
                        end
                        
                    end
                end
                
                res = i;
            catch
                res = app.Target;
            end

        end
        
        function res = windowStretch(app)

            try
                i = app.Target;
                
                for x = 1:size(i,1)
                    for y = 1:size(i,2)
                        if i(x,y) < 76 & i(x,y) > 27 
                            i(x,y) = ((227 * i(x,y)) - 5040)/47 ;
                        end
                    end
                end
                
                res = i;
            catch
                res = app.Target;
            end

        end
                
        function res = apply(app)
            
            applyS = true;
            applyT = true;
            
            t = app.Target;
            s = app.Target;
            
            if app.Operation == "To Gray"
                app.Target = toGray(app);
                t = app.Target;
                s = app.Target;
            
            elseif app.Operation == "window Stretch"
                app.Target = windowStretch(app);
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "To Peace Wise Rule"
                app.Target = toPeaceWiseRule(app);
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "To Peace Wise 80"
                app.Target = toPeaceWise80(app);
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "To Negative"
                app.Target = 255 - app.Target;
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Min Filter 3*3"
                app.Target = sFilters(app,"min",3);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Min Filter 5*5"
                app.Target = sFilters(app,"min",5);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Min Filter 7*7"
                app.Target = sFilters(app,"min",7);
                t = app.Target;
                s = app.Target;
            
            elseif app.Operation == "Max Filter 3*3"
                app.Target = sFilters(app,"max",3);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Max Filter 5*5"
                app.Target = sFilters(app,"max",5);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Max Filter 7*7"
                app.Target = sFilters(app,"max",7);
                t = app.Target;
                s = app.Target;
            
            elseif app.Operation == "Median Filter 3*3"
                app.Target = sFilters(app,"median",3);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Median Filter 5*5"
                app.Target = sFilters(app,"median",5);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Median Filter 7*7"
                app.Target = sFilters(app,"median",7);
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Red Channel"
                app.Target = channels(app,"r",false);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Green Channel"
                app.Target = channels(app,"g",false);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Blue Channel"
                app.Target = channels(app,"b",false);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Red Channel Colored"
                app.Target = channels(app,"r",true);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Green Channel Colored"
                app.Target = channels(app,"g",true);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Blue Channel Colored"
                app.Target = channels(app,"b",true);
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Phase-Only Image"
                app.Target = phase(app);
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Dilation Filter 5"
                app.Target = morph(app, "dilation");
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Erossion Filter 5"
                app.Target = morph(app, "erossion");
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Log Filter c=0.3"
                app.Target = logT(app, 0.3);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Log Filter c=0.7"
                app.Target = logT(app, 0.7);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Log Filter c=1"
                app.Target = logT(app, 1);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Log Filter c=2"
                app.Target = logT(app, 2);
                t = app.Target;
                s = app.Target;
            elseif app.Operation == "Log Filter c=3"
                app.Target = logT(app, 3);
                t = app.Target;
                s = app.Target;
                elseif app.Operation == "Log Filter c=4"
                app.Target = logT(app, 4);
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Laplacian Mask"
                app.Target = laplacian(app, 'mask');
                t = app.Target;
                s = app.Target; 
            elseif app.Operation == "Laplacian Image - Mask"
                app.Target = laplacian(app, 'image');
                t = app.Target;
                s = app.Target;     
            
                
            elseif app.Operation == "Histogram Matching with 1st window"
                app.Target = hMa(app);
                t = app.Target;
                s = app.Target; 
                
            elseif app.Operation == "Histogram Equalization"
                app.Target = hEq(app);
                t = app.Target;
                s = app.Target; 
                
            elseif app.Operation == "Gray Level Slicing"
                app.Target = gls(app);
                t = app.Target;
                s = app.Target; 
                
            elseif app.Operation == "Gray Level Reselution 7bits"
                app.Target = glr(app,7);
                t = app.Target;
                s = app.Target; 
            elseif app.Operation == "Gray Level Reselution 6bits"
                app.Target = glr(app,6);
                t = app.Target;
                s = app.Target; 
            elseif app.Operation == "Gray Level Reselution 5bits"
                app.Target = glr(app,5);
                t = app.Target;
                s = app.Target; 
            elseif app.Operation == "Gray Level Reselution 4bits"
                app.Target = glr(app,4);
                t = app.Target;
                s = app.Target; 
            elseif app.Operation == "Gray Level Reselution 3bits"
                app.Target = glr(app,3);
                t = app.Target;
                s = app.Target; 
            elseif app.Operation == "Gray Level Reselution 2bits"
                app.Target = glr(app,2);
                t = app.Target;
                s = app.Target; 
            elseif app.Operation == "Gray Level Reselution 1bits"
                app.Target = glr(app,1);
                t = app.Target;
                s = app.Target; 
                
            elseif app.Operation == "Gaussian Filter 16"
                app.Target = gaussianF16(app);
                t = app.Target;
                s = app.Target;  
                
            elseif app.Operation == "Gamma Transform c=1 y=0.4"
                app.Target = gammaT(app,1,0.4);
                t = app.Target;
                s = app.Target;  
                
            elseif app.Operation == "Gamma Transform c=1 y=0.7"
                app.Target = gammaT(app,1,0.7);
                t = app.Target;
                s = app.Target; 
                
            elseif app.Operation == "Gamma Transform c=1 y=1"
                app.Target = gammaT(app,1,1);
                t = app.Target;
                s = app.Target; 
                
            elseif app.Operation == "Gamma Transform c=1 y=2"
                app.Target = gammaT(app,1,2);
                t = app.Target;
                s = app.Target; 
                
            elseif app.Operation == "Gamma Transform c=1 y=3"
                app.Target = gammaT(app,1,3);
                t = app.Target;
                s = app.Target;  
                
            elseif app.Operation == "Gamma Transform c=1 y=4"
                app.Target = gammaT(app,1,3);
                t = app.Target;
                s = app.Target; 
                
            elseif app.Operation == "Gamma Transform c=1 y=5"
                app.Target = gammaT(app,1,3);
                t = app.Target;
                s = app.Target; 
                
            elseif app.Operation == "Fourier Transform"
                app.Target = fTransform(app,"f");
                t = app.Target;
                s = app.Target;                
                
            elseif app.Operation == "Shifted Fourier Transform"
                app.Target = fTransform(app,"sf");
                t = app.Target;
                s = app.Target;  
                
            elseif app.Operation == "Magnitude Fourier Transform"
                app.Target = fTransform(app,"m");
                t = app.Target;
                s = app.Target;  
                
            elseif app.Operation == "Gaussian Noise"
                app.Target = addNoise(app,"gaussian");
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Rayleigh Noise"
                app.Target = addNoise(app,"rayleigh");
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Gamma Noise"
                app.Target = addNoise(app,"gamma");
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Exponential Noise"
                app.Target = addNoise(app,"exp");
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Uniform Noise"
                app.Target = addNoise(app,"uniform");
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Salt & Pepper Noise"
                app.Target = addNoise(app,"salt & pepper");
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Poisson Noise"
                app.Target = addNoise(app,"poisson");
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Speckle Noise"
                app.Target = addNoise(app,"speckle");
                t = app.Target;
                s = app.Target;


            elseif app.Operation == "Average Filter 3*3"
                app.Target = averageFilter(app, 3*3 );
                t = app.Target;
                s = app.Target;
            
            elseif app.Operation == "Average Filter 5*5"
                app.Target = averageFilter(app, 7*7 );
                t = app.Target;
                s = app.Target;
                
            elseif app.Operation == "Average Filter 7*7"
                app.Target = averageFilter(app, 7*7 );
                t = app.Target;
                s = app.Target;
                
                
            elseif app.Operation == "Histogram"
                applyT = false;
                applyS = false;
                histogram(getUi(app), app.Target, 'BinLimits', [0, 255], 'BinWidth', 1);


            elseif app.Operation == "To Black & White"
                app.Target = im2bw(app.Target);
                s = app.Target;
                t = app.Target;


            elseif app.Operation == "Cat Stretch Filter"
                app.Target = catStretch(app);
                s = app.Target;
                t = app.Target;


            elseif app.Operation == "Contrast To Center"
                app.Target = centerContrast(app);
                s = app.Target;
                t = app.Target;


            elseif app.Operation == "Contrast To Dark"
                app.Target = darkContrast(app);
                s = app.Target;
                t = app.Target;


            elseif app.Operation == "Contrast To Light"
                app.Target = lightContrast(app);
                s = app.Target;
                t = app.Target;
    
                
            elseif app.Operation == "Contrast Enhance"
                app.Target = imadjust(app.Target);
                s = app.Target;
                t = app.Target;


            elseif app.Operation == "Fourier Butterworth Low 5 Image"
                app.Target = fFilter(app, "image", "butter", "low-pass", 5);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Butterworth Low 10 Image"
                app.Target = fFilter(app, "image", "butter", "low-pass", 10);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Butterworth Low 20 Image"
                app.Target = fFilter(app, "image", "butter", "low-pass", 20);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Butterworth Low 30 Image"
                app.Target = fFilter(app, "image", "butter", "low-pass", 30);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Butterworth Low 40 Image"
                app.Target = fFilter(app, "image", "butter", "low-pass", 40);
                s = app.Target;
                t = app.Target; 
 
            elseif app.Operation == "Fourier Butterworth Low 50 Image"
                app.Target = fFilter(app, "image", "butter", "low-pass", 50);
                s = app.Target;
                t = app.Target;   

            
            elseif app.Operation == "Fourier Gaussian Low 5 Image"
                app.Target = fFilter(app, "image", "gaussian", "low-pass", 5);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Gaussian Low 10 Image"
                app.Target = fFilter(app, "image", "gaussian", "low-pass", 10);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Gaussian Low 20 Image"
                app.Target = fFilter(app, "image", "gaussian", "low-pass", 20);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Gaussian Low 30 Image"
                app.Target = fFilter(app, "image", "gaussian", "low-pass", 30);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Gaussian Low 40 Image"
                app.Target = fFilter(app, "image", "gaussian", "low-pass", 40);
                s = app.Target;
                t = app.Target; 
 
            elseif app.Operation == "Fourier Gaussian Low 50 Image"
                app.Target = fFilter(app, "image", "gaussian", "low-pass", 50);
                s = app.Target;
                t = app.Target;
                
                
            elseif app.Operation == "Fourier Ideal Low 5 Image"
                app.Target = fFilter(app, "image", "gaussian", "low-pass", 5);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Ideal Low 10 Image"
                app.Target = fFilter(app, "image", "ideal", "low-pass", 10);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Ideal Low 20 Image"
                app.Target = fFilter(app, "image", "ideal", "low-pass", 20);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Ideal Low 30 Image"
                app.Target = fFilter(app, "image", "ideal", "low-pass", 30);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Ideal Low 40 Image"
                app.Target = fFilter(app, "image", "ideal", "low-pass", 40);
                s = app.Target;
                t = app.Target; 
 
            elseif app.Operation == "Fourier Ideal Low 50 Image"
                app.Target = fFilter(app, "image", "ideal", "low-pass", 50);
                s = app.Target;
                t = app.Target;


            elseif app.Operation == "Fourier Butterworth High 5 Image"
                app.Target = fFilter(app, "image", "butter", "high-pass", 5);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Butterworth High 10 Image"
                app.Target = fFilter(app, "image", "butter", "high-pass", 10);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Butterworth High 20 Image"
                app.Target = fFilter(app, "image", "butter", "high-pass", 20);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Butterworth High 30 Image"
                app.Target = fFilter(app, "image", "butter", "high-pass", 30);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Butterworth High 40 Image"
                app.Target = fFilter(app, "image", "butter", "high-pass", 40);
                s = app.Target;
                t = app.Target; 
 
            elseif app.Operation == "Fourier Butterworth High 50 Image"
                app.Target = fFilter(app, "image", "butter", "high-pass", 50);
                s = app.Target;
                t = app.Target;   

            
            elseif app.Operation == "Fourier Gaussian High 5 Image"
                app.Target = fFilter(app, "image", "gaussian", "high-pass", 5);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Gaussian High 10 Image"
                app.Target = fFilter(app, "image", "gaussian", "high-pass", 10);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Gaussian High 20 Image"
                app.Target = fFilter(app, "image", "gaussian", "high-pass", 20);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Gaussian High 30 Image"
                app.Target = fFilter(app, "image", "gaussian", "high-pass", 30);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Gaussian High 40 Image"
                app.Target = fFilter(app, "image", "gaussian", "high-pass", 40);
                s = app.Target;
                t = app.Target; 
 
            elseif app.Operation == "Fourier Gaussian High 50 Image"
                app.Target = fFilter(app, "image", "gaussian", "high-pass", 50);
                s = app.Target;
                t = app.Target;
                
                
            elseif app.Operation == "Fourier Ideal High 5 Image"
                app.Target = fFilter(app, "image", "gaussian", "high-pass", 5);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Ideal High 10 Image"
                app.Target = fFilter(app, "image", "ideal", "high-pass", 10);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Ideal High 20 Image"
                app.Target = fFilter(app, "image", "ideal", "high-pass", 20);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Ideal High 30 Image"
                app.Target = fFilter(app, "image", "ideal", "high-pass", 30);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Ideal High 40 Image"
                app.Target = fFilter(app, "image", "ideal", "high-pass", 40);
                s = app.Target;
                t = app.Target; 
 
            elseif app.Operation == "Fourier Ideal High 50 Image"
                app.Target = fFilter(app, "image", "ideal", "high-pass", 50);
                s = app.Target;
                t = app.Target;
                
            elseif app.Operation == "Fourier Butterworth Low 5 Filter 2D"
                k = fFilter(app, "filter", "butter", "low-pass", 5);
                s = k;
                
            elseif app.Operation == "Fourier Butterworth Low 10 Filter 2D"
                k = fFilter(app, "filter", "butter", "low-pass", 10);
                s = k;
                
            elseif app.Operation == "Fourier Butterworth Low 20 Filter 2D"
                k = fFilter(app, "filter", "butter", "low-pass", 20);
                s = k;
                
            elseif app.Operation == "Fourier Butterworth Low 30 Filter 2D"
                k = fFilter(app, "filter", "butter", "low-pass", 30);
                s = k;
                
            elseif app.Operation == "Fourier Butterworth Low 40 Filter 2D"
                k = fFilter(app, "filter", "butter", "low-pass", 40);
                s = k;
 
            elseif app.Operation == "Fourier Butterworth Low 50 Filter 2D"
                k = fFilter(app, "filter", "butter", "low-pass", 50);
                s = k;

            
            elseif app.Operation == "Fourier Gaussian Low 5 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 5);
                s = k;
                
            elseif app.Operation == "Fourier Gaussian Low 10 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 10);
                s = k;
                
            elseif app.Operation == "Fourier Gaussian Low 20 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 20);
                s = k;
                
            elseif app.Operation == "Fourier Gaussian Low 30 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 30);
                s = k;
                
            elseif app.Operation == "Fourier Gaussian Low 40 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 40);
                s = k;
 
            elseif app.Operation == "Fourier Gaussian Low 50 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 50);
                s = k;
                
                
            elseif app.Operation == "Fourier Ideal Low 5 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 5);
                s = k;
                
            elseif app.Operation == "Fourier Ideal Low 10 Filter 2D"
                k = fFilter(app, "filter", "ideal", "low-pass", 10);
                s = k;
                
            elseif app.Operation == "Fourier Ideal Low 20 Filter 2D"
                k = fFilter(app, "filter", "ideal", "low-pass", 20);
                s = k;
                
            elseif app.Operation == "Fourier Ideal Low 30 Filter 2D"
                k = fFilter(app, "filter", "ideal", "low-pass", 30);
                s = k;
                
            elseif app.Operation == "Fourier Ideal Low 40 Filter 2D"
                k = fFilter(app, "filter", "ideal", "low-pass", 40);
                s = k;
 
            elseif app.Operation == "Fourier Ideal Low 50 Filter 2D"
                k = fFilter(app, "filter", "ideal", "low-pass", 50);
                s = k;


            elseif app.Operation == "Fourier Butterworth High 5 Filter 2D"
                k = fFilter(app, "filter", "butter", "high-pass", 5);
                s = k;
                
            elseif app.Operation == "Fourier Butterworth High 10 Filter 2D"
                k = fFilter(app, "filter", "butter", "high-pass", 10);
                s = k;
                
            elseif app.Operation == "Fourier Butterworth High 20 Filter 2D"
                k = fFilter(app, "filter", "butter", "high-pass", 20);
                s = k;
                
            elseif app.Operation == "Fourier Butterworth High 30 Filter 2D"
                k = fFilter(app, "filter", "butter", "high-pass", 30);
                s = k;
                
            elseif app.Operation == "Fourier Butterworth High 40 Filter 2D"
                k = fFilter(app, "filter", "butter", "high-pass", 40);
                s = k;
 
            elseif app.Operation == "Fourier Butterworth High 50 Filter 2D"
                k = fFilter(app, "filter", "butter", "high-pass", 50);
                s = k;

            
            elseif app.Operation == "Fourier Gaussian High 5 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 5);
                s = k;
                
            elseif app.Operation == "Fourier Gaussian High 10 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 10);
                s = k;
                
            elseif app.Operation == "Fourier Gaussian High 20 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 20);
                s = k;
                
            elseif app.Operation == "Fourier Gaussian High 30 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 30);
                s = k;
                
            elseif app.Operation == "Fourier Gaussian High 40 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 40);
                s = k;
 
            elseif app.Operation == "Fourier Gaussian High 50 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 50);
                s = k;
                
                
            elseif app.Operation == "Fourier Ideal High 5 Filter 2D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 5);
                s = k;
                
            elseif app.Operation == "Fourier Ideal High 10 Filter 2D"
                k = fFilter(app, "filter", "ideal", "high-pass", 10);
                s = k;
                
            elseif app.Operation == "Fourier Ideal High 20 Filter 2D"
                k = fFilter(app, "filter", "ideal", "high-pass", 20);
                s = k;
                
            elseif app.Operation == "Fourier Ideal High 30 Filter 2D"
                k = fFilter(app, "filter", "ideal", "high-pass", 30);
                s = k;
                
            elseif app.Operation == "Fourier Ideal High 40 Filter 2D"
                k = fFilter(app, "filter", "ideal", "high-pass", 40);
                s = k;
 
            elseif app.Operation == "Fourier Ideal High 50 Filter 2D"
                k = fFilter(app, "filter", "ideal", "high-pass", 50);
                s = k;
            
            elseif app.Operation == "Fourier Butterworth Low 5 Filter 3D"
                k = fFilter(app, "filter", "butter", "low-pass", 5);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');

            elseif app.Operation == "Fourier Butterworth Low 10 Filter 3D"
                k = fFilter(app, "filter", "butter", "low-pass", 10);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Butterworth Low 20 Filter 3D"
                k = fFilter(app, "filter", "butter", "low-pass", 20);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Butterworth Low 30 Filter 3D"
                k = fFilter(app, "filter", "butter", "low-pass", 30);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Butterworth Low 40 Filter 3D"
                k = fFilter(app, "filter", "butter", "low-pass", 40);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
 
            elseif app.Operation == "Fourier Butterworth Low 50 Filter 3D"
                k = fFilter(app, "filter", "butter", "low-pass", 50);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');

            
            elseif app.Operation == "Fourier Gaussian Low 5 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 5);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Gaussian Low 10 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 10);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Gaussian Low 20 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 20);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Gaussian Low 30 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 30);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Gaussian Low 40 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 40);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
 
            elseif app.Operation == "Fourier Gaussian Low 50 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 50);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
                
            elseif app.Operation == "Fourier Ideal Low 5 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "low-pass", 5);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Ideal Low 10 Filter 3D"
                k = fFilter(app, "filter", "ideal", "low-pass", 10);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Ideal Low 20 Filter 3D"
                k = fFilter(app, "filter", "ideal", "low-pass", 20);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Ideal Low 30 Filter 3D"
                k = fFilter(app, "filter", "ideal", "low-pass", 30);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Ideal Low 40 Filter 3D"
                k = fFilter(app, "filter", "ideal", "low-pass", 40);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
 
            elseif app.Operation == "Fourier Ideal Low 50 Filter 3D"
                k = fFilter(app, "filter", "ideal", "low-pass", 50);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');


            elseif app.Operation == "Fourier Butterworth High 5 Filter 3D"
                k = fFilter(app, "filter", "butter", "high-pass", 5);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Butterworth High 10 Filter 3D"
                k = fFilter(app, "filter", "butter", "high-pass", 10);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Butterworth High 20 Filter 3D"
                k = fFilter(app, "filter", "butter", "high-pass", 20);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Butterworth High 30 Filter 3D"
                k = fFilter(app, "filter", "butter", "high-pass", 30);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Butterworth High 40 Filter 3D"
                k = fFilter(app, "filter", "butter", "high-pass", 40);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
 
            elseif app.Operation == "Fourier Butterworth High 50 Filter 3D"
                k = fFilter(app, "filter", "butter", "high-pass", 50);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');

            
            elseif app.Operation == "Fourier Gaussian High 5 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 5);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Gaussian High 10 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 10);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Gaussian High 20 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 20);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Gaussian High 30 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 30);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Gaussian High 40 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 40);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
 
            elseif app.Operation == "Fourier Gaussian High 50 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 50);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
                
            elseif app.Operation == "Fourier Ideal High 5 Filter 3D"
                k = fFilter(app, "filter", "gaussian", "high-pass", 5);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Ideal High 10 Filter 3D"
                k = fFilter(app, "filter", "ideal", "high-pass", 10);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Ideal High 20 Filter 3D"
                k = fFilter(app, "filter", "ideal", "high-pass", 20);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Ideal High 30 Filter 3D"
                k = fFilter(app, "filter", "ideal", "high-pass", 30);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            elseif app.Operation == "Fourier Ideal High 40 Filter 3D"
                k = fFilter(app, "filter", "ideal", "high-pass", 40);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
 
            elseif app.Operation == "Fourier Ideal High 50 Filter 3D"
                k = fFilter(app, "filter", "ideal", "high-pass", 50);
                applyT = false;
                applyS = false;
                ui = getUi(app); 
                surf(ui, k);
                shading(ui, 'interp');
                
            end
            
            if applyT
                imshow(t, "Parent", app.UIAxes4);
            end
            
            if applyS
                imshow(s, "Parent", getUi(app));
            end
            
            res = s;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            app.Target=imread(filename);
            imshow(app.Target,'Parent' ,app.UIAxes4);
            imshow(app.Target,'Parent' ,getUi(app));
            setRes(app, app.Target);
            app.ToGrayButton.Enable = "on";
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.Operation = value;
            app.ToGrayButton.Text = value;
        end

        % Button pushed function: SelectButton_10
        function SelectButton_10Pushed(app, event)
            enableSelects(app);
            app.SelectButton_10.Enable = "off";
            app.Selected = "10";
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            enableSelects(app);
            app.SelectButton_2.Enable = "off";
            app.Selected = "2";
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
            enableSelects(app);
            app.SelectButton_3.Enable = "off";
            app.Selected = "3";
        end

        % Button pushed function: SelectButton_4
        function SelectButton_4Pushed(app, event)
            enableSelects(app);
            app.SelectButton_4.Enable = "off";
            app.Selected = "4";
        end

        % Button pushed function: SelectButton_5
        function SelectButton_5Pushed(app, event)
            enableSelects(app);
            app.SelectButton_5.Enable = "off";
            app.Selected = "5";
        end

        % Button pushed function: SelectButton_6
        function SelectButton_6Pushed(app, event)
            enableSelects(app);
            app.SelectButton_6.Enable = "off";
            app.Selected = "6";
        end

        % Button pushed function: SelectButton_7
        function SelectButton_7Pushed(app, event)
            enableSelects(app);
            app.SelectButton_7.Enable = "off";
            app.Selected = "7";
        end

        % Button pushed function: SelectButton_8
        function SelectButton_8Pushed(app, event)
            enableSelects(app);
            app.SelectButton_8.Enable = "off";
            app.Selected = "8";
        end

        % Button pushed function: SelectButton_9
        function SelectButton_9Pushed(app, event)
            enableSelects(app);
            app.SelectButton_9.Enable = "off";
            app.Selected = "9";
        end

        % Button pushed function: ToGrayButton
        function ToGrayButtonPushed(app, event)
            res = apply(app);
            setRes(app, res);
            
        end

        % Button pushed function: TargetButton
        function TargetButtonPushed(app, event)
           app.Target = app.Res5;
           imshow(app.Target, "Parent", app.UIAxes4);
        end

        % Button pushed function: TargetButton_2
        function TargetButton_2Pushed(app, event)
           app.Target = app.Res8;
           imshow(app.Target, "Parent", app.UIAxes4);
        end

        % Button pushed function: TargetButton_3
        function TargetButton_3Pushed(app, event)
           app.Target = app.Res9;
           imshow(app.Target, "Parent", app.UIAxes4);
        end

        % Button pushed function: TargetButton_4
        function TargetButton_4Pushed(app, event)
           app.Target = app.Res6;
           imshow(app.Target, "Parent", app.UIAxes4);
        end

        % Button pushed function: TargetButton_6
        function TargetButton_6Pushed(app, event)
           app.Target = app.Res10;
           imshow(app.Target, "Parent", app.UIAxes4);
        end

        % Button pushed function: TargetButton_5
        function TargetButton_5Pushed(app, event)
           app.Target = app.Res7;
           imshow(app.Target, "Parent", app.UIAxes4);
        end

        % Button pushed function: TargetButton_7
        function TargetButton_7Pushed(app, event)
           app.Target = app.Res11;
           imshow(app.Target, "Parent", app.UIAxes4);
        end

        % Button pushed function: TargetButton_8
        function TargetButton_8Pushed(app, event)
           app.Target = app.Res12;
           imshow(app.Target, "Parent", app.UIAxes4);
        end

        % Button pushed function: TargetButton_9
        function TargetButton_9Pushed(app, event)
           app.Target = app.Res13;
           imshow(app.Target, "Parent", app.UIAxes4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DigitalImageProcessingByKodoYousifUIFigure = uifigure('Visible', 'off');
            app.DigitalImageProcessingByKodoYousifUIFigure.Name = 'Digital Image Processing By Kodo Yousif';
            app.DigitalImageProcessingByKodoYousifUIFigure.Icon = 't1.jpg';
            app.GridLayout = uigridlayout(app.DigitalImageProcessingByKodoYousifUIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Target')
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.OperationsPanel = uipanel(app.GridLayout5);
            app.OperationsPanel.Title = 'Operations';
            app.GridLayout6 = uigridlayout(app.OperationsPanel);
            app.ListBox = uilistbox(app.GridLayout6);
            app.ListBox.Items = {'Histogram', 'To Gray', 'To Negative', 'To Black & White', 'Red Channel', 'Red Channel Colored', 'Green Channel', 'Green Channel Colored', 'Blue Channel', 'Blue Channel Colored', 'Rayleigh Noise', 'Gamma Noise', 'Exponential Noise', 'Uniform Noise', 'Gaussian Noise', 'Salt & Pepper Noise', 'Poisson Noise', 'Speckle Noise', 'window Stretch', 'To Peace Wise 80', 'To Peace Wise Rule', 'Phase-Only Image', 'Dilation Filter 5', 'Erossion Filter 5', 'Average Filter 3*3', 'Average Filter 5*5', 'Average Filter 7*7', 'Min Filter 3*3', 'Min Filter 5*5', 'Min Filter 7*7', 'Max Filter 3*3', 'Max Filter 5*5', 'Max Filter 7*7', 'Median Filter 3*3', 'Median Filter 5*5', 'Median Filter 7*7', 'Cat Stretch Filter', 'Contrast To Center', 'Contrast To Dark', 'Contrast To Light', 'Contrast Enhance', 'Histogram Equalization', 'Histogram Matching with 1st window', 'Laplacian Mask', 'Laplacian Image - Mask', 'Gaussian Filter 16', 'Log Filter c=0.3', 'Log Filter c=0.7', 'Log Filter c=1', 'Log Filter c=2', 'Log Filter c=3', 'Log Filter c=4', 'Gamma Transform c=1 y=0.4', 'Gamma Transform c=1 y=0.7', 'Gamma Transform c=1 y=1', 'Gamma Transform c=1 y=2', 'Gamma Transform c=1 y=3', 'Gamma Transform c=1 y=4', 'Gamma Transform c=1 y=5', 'Gray Level Slicing', 'Gray Level Reselution 7bits', 'Gray Level Reselution 6bits', 'Gray Level Reselution 5bits', 'Gray Level Reselution 4bits', 'Gray Level Reselution 3bits', 'Gray Level Reselution 2bits', 'Gray Level Reselution 1bits', 'Fourier Transform', 'Shifted Fourier Transform', 'Magnitude Fourier Transform', 'Fourier Butterworth Low 5 Image', 'Fourier Butterworth Low 10 Image', 'Fourier Butterworth Low 20 Image', 'Fourier Butterworth Low 30 Image', 'Fourier Butterworth Low 40 Image', 'Fourier Butterworth Low 50 Image', 'Fourier Ideal Low 5 Image', 'Fourier Ideal Low 10 Image', 'Fourier Ideal Low 20 Image', 'Fourier Ideal Low 30 Image', 'Fourier Ideal Low 40 Image', 'Fourier Ideal Low 50 Image', 'Fourier Gaussian Low 5 Image', 'Fourier Gaussian Low 10 Image', 'Fourier Gaussian Low 20 Image', 'Fourier Gaussian Low 30 Image', 'Fourier Gaussian Low 40 Image', 'Fourier Gaussian Low 50 Image', 'Fourier Butterworth High 5 Image', 'Fourier Butterworth High 10 Image', 'Fourier Butterworth High 20 Image', 'Fourier Butterworth High 30 Image', 'Fourier Butterworth High 40 Image', 'Fourier Butterworth High 50 Image', 'Fourier Ideal High 5 Image', 'Fourier Ideal High 10 Image', 'Fourier Ideal High 20 Image', 'Fourier Ideal High 30 Image', 'Fourier Ideal High 40 Image', 'Fourier Ideal High 50 Image', 'Fourier Gaussian High 5 Image', 'Fourier Gaussian High 10 Image', 'Fourier Gaussian High 20 Image', 'Fourier Gaussian High 30 Image', 'Fourier Gaussian High 40 Image', 'Fourier Gaussian High 50 Image', 'Fourier Butterworth Low 5 Filter 2D', 'Fourier Butterworth Low 10 Filter 2D', 'Fourier Butterworth Low 20 Filter 2D', 'Fourier Butterworth Low 30 Filter 2D', 'Fourier Butterworth Low 40 Filter 2D', 'Fourier Butterworth Low 50 Filter 2D', 'Fourier Ideal Low 5 Filter 2D', 'Fourier Ideal Low 10 Filter 2D', 'Fourier Ideal Low 20 Filter 2D', 'Fourier Ideal Low 30 Filter 2D', 'Fourier Ideal Low 40 Filter 2D', 'Fourier Ideal Low 50 Filter 2D', 'Fourier Gaussian Low 5 Filter 2D', 'Fourier Gaussian Low 10 Filter 2D', 'Fourier Gaussian Low 20 Filter 2D', 'Fourier Gaussian Low 30 Filter 2D', 'Fourier Gaussian Low 40 Filter 2D', 'Fourier Gaussian Low 50 Filter 2D', 'Fourier Butterworth High 5 Filter 2D', 'Fourier Butterworth High 10 Filter 2D', 'Fourier Butterworth High 20 Filter 2D', 'Fourier Butterworth High 30 Filter 2D', 'Fourier Butterworth High 40 Filter 2D', 'Fourier Butterworth High 50 Filter 2D', 'Fourier Ideal High 5 Filter 2D', 'Fourier Ideal High 10 Filter 2D', 'Fourier Ideal High 20 Filter 2D', 'Fourier Ideal High 30 Filter 2D', 'Fourier Ideal High 40 Filter 2D', 'Fourier Ideal High 50 Filter 2D', 'Fourier Gaussian High 5 Filter 2D', 'Fourier Gaussian High 10 Filter 2D', 'Fourier Gaussian High 20 Filter 2D', 'Fourier Gaussian High 30 Filter 2D', 'Fourier Gaussian High 40 Filter 2D', 'Fourier Gaussian High 50 Filter 2D', 'Fourier Butterworth Low 5 Filter 3D', 'Fourier Butterworth Low 10 Filter 3D', 'Fourier Butterworth Low 20 Filter 3D', 'Fourier Butterworth Low 30 Filter 3D', 'Fourier Butterworth Low 40 Filter 3D', 'Fourier Butterworth Low 50 Filter 3D', 'Fourier Ideal Low 5 Filter 3D', 'Fourier Ideal Low 10 Filter 3D', 'Fourier Ideal Low 20 Filter 3D', 'Fourier Ideal Low 30 Filter 3D', 'Fourier Ideal Low 40 Filter 3D', 'Fourier Ideal Low 50 Filter 3D', 'Fourier Gaussian Low 5 Filter 3D', 'Fourier Gaussian Low 10 Filter 3D', 'Fourier Gaussian Low 20 Filter 3D', 'Fourier Gaussian Low 30 Filter 3D', 'Fourier Gaussian Low 40 Filter 3D', 'Fourier Gaussian Low 50 Filter 3D', 'Fourier Butterworth High 5 Filter 3D', 'Fourier Butterworth High 10 Filter 3D', 'Fourier Butterworth High 20 Filter 3D', 'Fourier Butterworth High 30 Filter 3D', 'Fourier Butterworth High 40 Filter 3D', 'Fourier Butterworth High 50 Filter 3D', 'Fourier Ideal High 5 Filter 3D', 'Fourier Ideal High 10 Filter 3D', 'Fourier Ideal High 20 Filter 3D', 'Fourier Ideal High 30 Filter 3D', 'Fourier Ideal High 40 Filter 3D', 'Fourier Ideal High 50 Filter 3D', 'Fourier Gaussian High 5 Filter 3D', 'Fourier Gaussian High 10 Filter 3D', 'Fourier Gaussian High 20 Filter 3D', 'Fourier Gaussian High 30 Filter 3D', 'Fourier Gaussian High 40 Filter 3D', 'Fourier Gaussian High 50 Filter 3D'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'To Gray';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.UploadButton = uibutton(app.GridLayout7, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ToGrayButton = uibutton(app.GridLayout7, 'push');
            app.ToGrayButton.ButtonPushedFcn = createCallbackFcn(app, @ToGrayButtonPushed, true);
            app.ToGrayButton.Enable = 'off';
            app.ToGrayButton.Text = 'To Gray';
            app.ResultsPanel = uipanel(app.GridLayout);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout8 = uigridlayout(app.ResultsPanel);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.UIAxes5 = uiaxes(app.GridLayout9);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.SelectButton_10 = uibutton(app.GridLayout10, 'push');
            app.SelectButton_10.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_10Pushed, true);
            app.SelectButton_10.Enable = 'off';
            app.SelectButton_10.Text = 'Select';
            app.TargetButton = uibutton(app.GridLayout10, 'push');
            app.TargetButton.ButtonPushedFcn = createCallbackFcn(app, @TargetButtonPushed, true);
            app.TargetButton.Text = 'Target';
            app.GridLayout11 = uigridlayout(app.GridLayout8);
            app.UIAxes8 = uiaxes(app.GridLayout11);
            app.GridLayout19 = uigridlayout(app.GridLayout11);
            app.SelectButton_2 = uibutton(app.GridLayout19, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.TargetButton_2 = uibutton(app.GridLayout19, 'push');
            app.TargetButton_2.ButtonPushedFcn = createCallbackFcn(app, @TargetButton_2Pushed, true);
            app.TargetButton_2.Text = 'Target';
            app.GridLayout12 = uigridlayout(app.GridLayout8);
            app.UIAxes9 = uiaxes(app.GridLayout12);
            app.GridLayout20 = uigridlayout(app.GridLayout12);
            app.SelectButton_3 = uibutton(app.GridLayout20, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select';
            app.TargetButton_3 = uibutton(app.GridLayout20, 'push');
            app.TargetButton_3.ButtonPushedFcn = createCallbackFcn(app, @TargetButton_3Pushed, true);
            app.TargetButton_3.Text = 'Target';
            app.GridLayout13 = uigridlayout(app.GridLayout8);
            app.UIAxes7 = uiaxes(app.GridLayout13);
            app.GridLayout22 = uigridlayout(app.GridLayout13);
            app.SelectButton_5 = uibutton(app.GridLayout22, 'push');
            app.SelectButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_5Pushed, true);
            app.SelectButton_5.Text = 'Select';
            app.TargetButton_5 = uibutton(app.GridLayout22, 'push');
            app.TargetButton_5.ButtonPushedFcn = createCallbackFcn(app, @TargetButton_5Pushed, true);
            app.TargetButton_5.Text = 'Target';
            app.GridLayout14 = uigridlayout(app.GridLayout8);
            app.UIAxes6 = uiaxes(app.GridLayout14);
            app.GridLayout21 = uigridlayout(app.GridLayout14);
            app.SelectButton_4 = uibutton(app.GridLayout21, 'push');
            app.SelectButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_4Pushed, true);
            app.SelectButton_4.Text = 'Select';
            app.TargetButton_4 = uibutton(app.GridLayout21, 'push');
            app.TargetButton_4.ButtonPushedFcn = createCallbackFcn(app, @TargetButton_4Pushed, true);
            app.TargetButton_4.Text = 'Target';
            app.GridLayout15 = uigridlayout(app.GridLayout8);
            app.UIAxes11 = uiaxes(app.GridLayout15);
            app.GridLayout24 = uigridlayout(app.GridLayout15);
            app.SelectButton_7 = uibutton(app.GridLayout24, 'push');
            app.SelectButton_7.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_7Pushed, true);
            app.SelectButton_7.Text = 'Select';
            app.TargetButton_7 = uibutton(app.GridLayout24, 'push');
            app.TargetButton_7.ButtonPushedFcn = createCallbackFcn(app, @TargetButton_7Pushed, true);
            app.TargetButton_7.Text = 'Target';
            app.GridLayout16 = uigridlayout(app.GridLayout8);
            app.UIAxes10 = uiaxes(app.GridLayout16);
            app.GridLayout23 = uigridlayout(app.GridLayout16);
            app.SelectButton_6 = uibutton(app.GridLayout23, 'push');
            app.SelectButton_6.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_6Pushed, true);
            app.SelectButton_6.Text = 'Select';
            app.TargetButton_6 = uibutton(app.GridLayout23, 'push');
            app.TargetButton_6.ButtonPushedFcn = createCallbackFcn(app, @TargetButton_6Pushed, true);
            app.TargetButton_6.Text = 'Target';
            app.GridLayout17 = uigridlayout(app.GridLayout8);
            app.UIAxes13 = uiaxes(app.GridLayout17);
            app.GridLayout26 = uigridlayout(app.GridLayout17);
            app.SelectButton_9 = uibutton(app.GridLayout26, 'push');
            app.SelectButton_9.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_9Pushed, true);
            app.SelectButton_9.Text = 'Select';
            app.TargetButton_9 = uibutton(app.GridLayout26, 'push');
            app.TargetButton_9.ButtonPushedFcn = createCallbackFcn(app, @TargetButton_9Pushed, true);
            app.TargetButton_9.Text = 'Target';
            app.GridLayout18 = uigridlayout(app.GridLayout8);
            app.UIAxes12 = uiaxes(app.GridLayout18);
            app.GridLayout25 = uigridlayout(app.GridLayout18);
            app.SelectButton_8 = uibutton(app.GridLayout25, 'push');
            app.SelectButton_8.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_8Pushed, true);
            app.SelectButton_8.Text = 'Select';
            app.TargetButton_8 = uibutton(app.GridLayout25, 'push');
            app.TargetButton_8.ButtonPushedFcn = createCallbackFcn(app, @TargetButton_8Pushed, true);
            app.TargetButton_8.Text = 'Target';
            % Show the figure after all components are created
            app.DigitalImageProcessingByKodoYousifUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DigitalImageProcessingByKodoYousifUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DigitalImageProcessingByKodoYousifUIFigure)
        end
    end
end